classdef CsvEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CSVEditorUIFigure       matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        Menu_Load               matlab.ui.container.Menu
        Menu_Save               matlab.ui.container.Menu
        Menu_SaveAs             matlab.ui.container.Menu
        SettingsMenu            matlab.ui.container.Menu
        Menu_ToggleColumnNames  matlab.ui.container.Menu
        Menu_ToggleRowNames     matlab.ui.container.Menu
        Grid_Main               matlab.ui.container.GridLayout
        UITable                 matlab.ui.control.Table
    end

    properties (Access = private)
        TableHistory;
    end

    methods (Access = private)
        
        function UpdateCellEdit(app)
            % Update properties for editing cells for new rows or columns.
            [app.UITable.ColumnEditable, app.UITable.ColumnSortable] = ...
                deal(true(1, size(app.UITable.Data, 2)));
        end
        
        function LoadFile(app, filePath)
            % readtable is dumb and can't handle empty columns at the end of a file...
            % Other file-parsing functions also do not handle string types well, so manually parse file contents.
            fid = fopen(filePath, 'r');
            if (fid == -1)
                uialert(app.CSVEditorUIFigure, ...
                    sprintf('Unable to open %s for reading.', filePath), 'Error Loading File');
                return;
            end

            cleanup  = onCleanup(@() fclose(fid));
            fileData = textscan(fid, '%s', 'delimiter', newline);
            fileData = fileData{1};

            if (isempty(fileData))
                uialert(app.CSVEditorUIFigure, ...
                    sprintf('No valid table data found in file %s.', filePath), 'Error Loading File');
                return;
            end

            rowData = cell(size(fileData));
            for i = 1:length(rowData)
                % TO-DO: Handle string-wrapped values.
                % TO-DO: Verify that data was read in correctly/equal number of columns.
                rowData{i} = regexp(fileData{i}, ',', 'split');
            end
            columnCount = max(cellfun(@length, rowData));
            tableData   = repmat({''}, length(rowData), columnCount);

            for i = 1:length(rowData)
                tableData(i, 1:length(rowData{i})) = rowData{i};
            end

            UpdateTableData(app, tableData);
        end
        
        function UpdateTableData(app, tableData, ignoreHistory)
            if (app.Menu_ToggleColumnNames.Checked)
                app.UITable.ColumnName = tableData(1, :);
                app.UITable.Data       = tableData(2:end, :);
            else
                app.UITable.ColumnName = arrayfun(@(x) sprintf('Column %d', x), ...
                    1:size(tableData, 2), 'uniformOutput', false)';
                app.UITable.Data       = tableData;
            end

            % TO-DO: Handle optional row names.
            if (app.Menu_ToggleRowNames.Checked)

            else

            end

            UpdateCellEdit(app);

            if (nargin < 3 || ~ignoreHistory)
                UpdateHistory(app);
            end
        end
        
        function tableData = GetTableData(app, columnIndices, rowIndices)
            % Return cell array table data, including column names if applicable.
            tableData   = app.UITable.Data;
            columnNames = app.UITable.ColumnName';
            rowNames    = ''; % TO-DO: Implement.

            if (nargin > 1 && ~isempty(columnIndices))
                tableData   = tableData(:, columnIndices);
                columnNames = columnNames(columnIndices(columnIndices <= length(columnNames)));
            end

            if (nargin > 2 && ~isempty(rowIndices))
                tableData = tableData(rowIndices, :);
            end

            if (app.Menu_ToggleColumnNames.Checked)
                tableData = [columnNames; tableData];
            end
        end
        
        function AddColumn(app, columnIndex)
            % TO-DO: Allow for multiple columns at once.
            tableData   = GetTableData(app);
            emptyColumn = repmat({''}, size(tableData, 1), 1);
            if (app.Menu_ToggleColumnNames.Checked)
                % Prompt user for column name.
                userInput = inputdlg('Enter new column name:', 'Column Name');
                if (isempty(userInput))
                    return; % User canceled.
                end

                emptyColumn{1} = userInput{:};
            end

            if (nargin > 1 && ~isempty(columnIndex))
                % TO-DO: Verify bounds.
                tableData = [tableData(:, 1:columnIndex - 1), emptyColumn, tableData(:, columnIndex:end)];
            else
                tableData = [tableData, emptyColumn];
            end

            UpdateTableData(app, tableData);
        end
        
        function AddRow(app, rowIndex)
            % TO-DO: Allow for multiple rows at once.
            tableData = GetTableData(app);
            emptyRow  = repmat({''}, 1, size(tableData, 2));
            if (app.Menu_ToggleRowNames.Checked)
                % Prompt user for column name.
                userInput   = inputdlg('Enter new row name:', 'Row Name');
                if (isempty(userInput))
                    return; % User canceled.
                end

                emptyRow{1} = userInput{:};
            end

            if (nargin > 1 && ~isempty(rowIndex))
                % TO-DO: Verify bounds.
                rowIndex  = rowIndex + app.Menu_ToggleColumnNames.Checked;
                tableData = [tableData(1:rowIndex - 1, :); emptyRow; tableData(rowIndex:end, :)];
            else
                tableData = [tableData; emptyRow];
            end

            UpdateTableData(app, tableData);
        end
        
        function RemoveColumn(app, selectedIndex)
            % Determine which column is selected and remove it (warn user first).
            if (~isempty(selectedIndex))
                %userInput     = uiconfirm(app.CSVEditorUIFigure, 'Remove selected columns?', 'Confirm Removal', ...
                %    'icon', 'warning');
                if (true || strcmpi(userInput, 'ok'))
                    validIndices = setdiff(1:size(app.UITable.Data, 2), selectedIndex);
                    tableData    = GetTableData(app, validIndices);
                    UpdateTableData(app, tableData);
                end
            end
        end
        
        function RemoveRow(app, selectedIndex)
            % Determine which row is selected and remove it (warn user first).
            if (~isempty(selectedIndex))
                %userInput = uiconfirm(app.CSVEditorUIFigure, 'Remove selected rows?', 'Confirm Removal', ...
                %    'icon', 'warning');
                if (true || strcmpi(userInput, 'ok'))
                    validIndices = setdiff(1:size(app.UITable.Data, 1), selectedIndex);
                    tableData    = GetTableData(app, [], validIndices);
                    UpdateTableData(app, tableData);
                end
            end
        end
        
        function UpdateHistory(app)
            app.TableHistory.UpdateHistory(GetTableData(app));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup(app, varargin)
            % app.UITable.RearrangeableColumns = true;

            % Create table history array.
            app.TableHistory = History(50);

            % TO-DO: Create blank table that can be configured.
            LoadFile(app, 'Test.csv'); % TO-DO: Remove hard-coded file.

            % Create the context menu and set up callbacks.
            contextMenu  = uicontextmenu(app.CSVEditorUIFigure);
            uimenu(contextMenu, 'text', 'Add Column', ...
                'callback', @(txt, event) Button_AddColumnPushed(app, event));
            uimenu(contextMenu, 'text', 'Remove Column', ...
                'callback', @(txt, event) Button_RemoveColumnPushed(app, event));
            uimenu(contextMenu, 'text', 'Add Row', ...
                'callback', @(txt, event) Button_AddRowPushed(app, event));
            uimenu(contextMenu, 'text', 'Remove Row', ...
                'callback', @(txt, event) Button_RemoveRowPushed(app, event));

            app.UITable.ContextMenu = contextMenu;
        end

        % Key press function: UITable
        function Key_UITable(app, event)
            % Determine what kind of key press is being commanded.
            key = event.Key;

            if (isequal(event.Modifier, {'alt'}))
                % Add new row/column based on current selection location and directional input.
                switch (key)
                    case 'leftarrow'
                        selectedIndices = app.UITable.Selection(:, 2);
                        AddColumn(app, selectedIndices);
                        app.UITable.Selection(:, 2) = selectedIndices;
                    case 'rightarrow'
                        selectedIndices = app.UITable.Selection(:, 2);
                        AddColumn(app, selectedIndices + 1);
                    case 'downarrow'
                        selectedIndices = app.UITable.Selection(:, 1);
                        AddRow(app, selectedIndices + 1);
                    case 'uparrow'
                        selectedIndices = app.UITable.Selection(:, 1);
                        AddRow(app, selectedIndices);
                        app.UITable.Selection(:, 1) = selectedIndices;
                    otherwise
                        return; % Do nothing.
                end
            end

            if (isequal(event.Modifier, {'control', 'alt'}))
                % Remove row/column based on current selection location and directional input.
                switch (key)
                    case 'leftarrow'
                        selectedIndices = app.UITable.Selection(:, 2);
                        RemoveColumn(app, selectedIndices);
                    case 'rightarrow'
                        selectedIndices = app.UITable.Selection(:, 2);
                        RemoveColumn(app, selectedIndices);
                    case 'downarrow'
                        selectedIndices = app.UITable.Selection(:, 1);
                        RemoveRow(app, selectedIndices);
                    case 'uparrow'
                        selectedIndices = app.UITable.Selection(:, 1);
                        RemoveRow(app, selectedIndices);
                    otherwise
                        return; % Do nothing.
                end
            end

            if (isequal(event.Modifier, {'control'}))
                switch (key)
                    case 'z'
                        % Allow for undo-ing table modification (need to keep history).
                        if (app.TableHistory.ShiftBack())
                            [~, tableData] = app.TableHistory.GetCurrent();
                            UpdateTableData(app, tableData, true);
                        end

                        return;
                    case 'y'
                        % Redo table modification (restore history index).
                        if (app.TableHistory.ShiftForward())
                            [~, tableData] = app.TableHistory.GetCurrent();
                            UpdateTableData(app, tableData, true);
                        end

                        return;
                    case 'leftarrow'
                        % Swap current highlighted column with that to its left.
                        selectedIndices    = app.UITable.Selection(:, 2);
                        currentOrder       = 1:size(GetTableData(app), 2);
                        nonSelectedIndices = setxor(currentOrder, selectedIndices);
                        if (isempty(nonSelectedIndices))
                            return;
                        end

                        [currentOrder, app.UITable.Selection(:, 2)] = ...
                            ShiftDown(currentOrder, selectedIndices, nonSelectedIndices);
                        tableData = GetTableData(app, currentOrder);
                    case 'rightarrow'
                        % Swap current highlighted column with that to its left.
                        selectedIndices = app.UITable.Selection(:, 2);
                        currentOrder       = 1:size(GetTableData(app), 2);
                        nonSelectedIndices = setxor(currentOrder, selectedIndices);
                        if (isempty(nonSelectedIndices))
                            return;
                        end

                        [currentOrder, app.UITable.Selection(:, 2)] = ...
                            ShiftUp(currentOrder, selectedIndices, nonSelectedIndices);
                        tableData = GetTableData(app, currentOrder);
                    case 'downarrow'
                        indexAdjustment = logical(app.Menu_ToggleColumnNames.Checked);

                        % Swap current highlighted column with that to its left.
                        selectedIndices    = app.UITable.Selection(:, 1);
                        currentOrder       = 1:(size(GetTableData(app), 1) - indexAdjustment);
                        nonSelectedIndices = setxor(currentOrder, selectedIndices);
                        if (isempty(nonSelectedIndices))
                            return;
                        end

                        [currentOrder, app.UITable.Selection(:, 1)] = ...
                            ShiftUp(currentOrder, selectedIndices, nonSelectedIndices);
                        tableData = GetTableData(app, [], currentOrder);
                    case 'uparrow'
                        indexAdjustment = logical(app.Menu_ToggleColumnNames.Checked);

                        % Swap current highlighted column with that to its left.
                        selectedIndices    = app.UITable.Selection(:, 1);
                        currentOrder       = 1:(size(GetTableData(app), 1) - indexAdjustment);
                        nonSelectedIndices = setxor(currentOrder, selectedIndices);
                        if (isempty(nonSelectedIndices))
                            return;
                        end

                        [currentOrder, app.UITable.Selection(:, 1)] = ...
                            ShiftDown(currentOrder, selectedIndices, nonSelectedIndices);
                        tableData = GetTableData(app, [], currentOrder);
                    otherwise
                        % Ignore this key-press.
                        return;
                end

                UpdateTableData(app, tableData);
            end

            function [currentOrder, newSelection] = ShiftDown(currentOrder, selectedIndices, nonSelectedIndices)
                % Swap each index left/down (decrease index).
                uniqueIndices = unique(selectedIndices);
                newSelection  = selectedIndices;
                shiftedPoints = false(size(uniqueIndices));

                for i = 1:length(uniqueIndices)
                    matchingIndices = selectedIndices == uniqueIndices(i);

                    previousIndex = any(uniqueIndices(i) - nonSelectedIndices == 1) || ...
                        any(uniqueIndices(i) - uniqueIndices == 1 & shiftedPoints);
                    if (previousIndex)
                        currentOrder(uniqueIndices(i))     = currentOrder(uniqueIndices(i) - 1);
                        currentOrder(uniqueIndices(i) - 1) = uniqueIndices(i);
                        newSelection(matchingIndices)      = uniqueIndices(i) - 1;
                        shiftedPoints(i)                   = true;
                    end
                end
            end

            function [currentOrder, newSelection] = ShiftUp(currentOrder, selectedIndices, nonSelectedIndices)
                % Swap each index right/up (increase index).
                selectedIndices = flip(selectedIndices); % Process in reverse order to prevent overwriting.
                uniqueIndices   = flip(unique(selectedIndices));
                newSelection    = selectedIndices;
                shiftedPoints   = false(size(uniqueIndices));

                for i = 1:length(uniqueIndices)
                    matchingIndices = selectedIndices == uniqueIndices(i);

                    nextIndex = any(uniqueIndices(i) - nonSelectedIndices == -1) || ...
                        any(uniqueIndices(i) - uniqueIndices == -1 & shiftedPoints);
                    if (nextIndex)
                        currentOrder(uniqueIndices(i))     = currentOrder(uniqueIndices(i) + 1);
                        currentOrder(uniqueIndices(i) + 1) = uniqueIndices(i);
                        newSelection(matchingIndices)      = uniqueIndices(i) + 1;
                        shiftedPoints(i)                   = true;
                    end
                end

                newSelection = flip(newSelection); % Flip back to the original order.
            end
        end

        % Callback function
        function Button_AddColumnPushed(app, event)
            AddColumn(app);
        end

        % Callback function
        function Button_RemoveColumnPushed(app, event)
            % Determine which column is selected and remove it (warn user first).
            tableSelection = app.UITable.Selection;
            if (~isempty(tableSelection))
                selectedIndex = app.UITable.Selection(:, 2);
                RemoveColumn(app, selectedIndex);
            end
        end

        % Callback function
        function Button_AddRowPushed(app, event)
            AddRow(app);
        end

        % Callback function
        function Button_RemoveRowPushed(app, event)
            tableSelection = app.UITable.Selection;
            if (~isempty(tableSelection))
                selectedIndex = app.UITable.Selection(:, 1);
                RemoveRow(app, selectedIndex);
            end
        end

        % Menu selected function: Menu_ToggleColumnNames
        function Menu_ToggleColumnNamesSelected(app, event)
            tableData = GetTableData(app);
            app.Menu_ToggleColumnNames.Checked = ~app.Menu_ToggleColumnNames.Checked;
            UpdateTableData(app, tableData);
        end

        % Menu selected function: Menu_ToggleRowNames
        function Menu_ToggleRowNamesSelected(app, event)
            tableData = GetTableData(app);
            app.Menu_ToggleRowNames.Checked = ~app.Menu_ToggleRowNames.Checked;
            UpdateTableData(app, tableData);
        end

        % Menu selected function: Menu_Save
        function Menu_SaveSelected(app, event)
            % TO-DO
            uialert(app.CSVEditorUIFigure, 'Not implemented - use Save As method.', 'Invalid Callback');
        end

        % Menu selected function: Menu_SaveAs
        function Menu_SaveAsSelected(app, event)
            % TO-DO: Compare displayed data to table data and ask user if filtered columns should be saved.
            filePath = uiputfile('*.csv');
            if (isnumeric(filePath))
                return; % User cancled.
            end

            % Save the current table as a CSV file.
            fid     = fopen(filePath, 'w');
            cleanup = onCleanup(@() fclose(fid));

            if (fid == -1)
                uialert(app.CSVEditorUIFigure, 'Unable to open file for writing.', 'Invalid File');
                return; % Unable to create file.
            end

            tableData = GetTableData(app);
            for i = 1:size(tableData, 1)
                fprintf(fid, '%s', strjoin(tableData(i, :), ','));
                if (i < size(tableData, 1))
                    fprintf(fid, '\n');
                end
            end
        end

        % Menu selected function: Menu_Load
        function Menu_LoadSelected(app, event)
            filePath = uigetfile('*.csv');
            if (isnumeric(filePath))
                return; % User canceled.
            end

            LoadFile(app, filePath);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            UpdateHistory(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CSVEditorUIFigure = uifigure('Visible', 'off');
            app.CSVEditorUIFigure.Name = 'CSV Editor';
            app.Menu = uimenu(app.CSVEditorUIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_Load = uimenu(app.Menu);
            app.Menu_Load.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadSelected, true);
            app.Menu_Load.Text = 'Load';
            app.Menu_Save = uimenu(app.Menu);
            app.Menu_Save.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveSelected, true);
            app.Menu_Save.Text = 'Save';
            app.Menu_SaveAs = uimenu(app.Menu);
            app.Menu_SaveAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveAsSelected, true);
            app.Menu_SaveAs.Text = 'Save As';
            app.SettingsMenu = uimenu(app.CSVEditorUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.Menu_ToggleColumnNames = uimenu(app.SettingsMenu);
            app.Menu_ToggleColumnNames.MenuSelectedFcn = createCallbackFcn(app, @Menu_ToggleColumnNamesSelected, true);
            app.Menu_ToggleColumnNames.Checked = 'on';
            app.Menu_ToggleColumnNames.Text = 'Use Column Names';
            app.Menu_ToggleRowNames = uimenu(app.SettingsMenu);
            app.Menu_ToggleRowNames.MenuSelectedFcn = createCallbackFcn(app, @Menu_ToggleRowNamesSelected, true);
            app.Menu_ToggleRowNames.Visible = 'off';
            app.Menu_ToggleRowNames.Enable = 'off';
            app.Menu_ToggleRowNames.Text = 'Use Row Names';
            app.Grid_Main = uigridlayout(app.CSVEditorUIFigure);
            app.UITable = uitable(app.Grid_Main);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true];
            app.UITable.ColumnEditable = [true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @Key_UITable, true);
            % Show the figure after all components are created
            app.CSVEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CsvEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CSVEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Startup(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CSVEditorUIFigure)
        end
    end
end